classdef ClusterTable < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UITable                     matlab.ui.control.Table
        UnknownLabel                matlab.ui.control.Label
        DatabricksEnvironmentLabel  matlab.ui.control.Label
        ListClustersButton          matlab.ui.control.Button
    end


    methods (Access = private)

        function clusterList2Table(app, cl)
            arguments (Input)
                app 
                cl databricks.Cluster
            end         

            varNames = ["Name", "Id", "State", "Spark Version"];
            varTypes = ["string", "string", "string", "string"];
            t = table('Size', [numel(cl), numel(varNames)], 'VariableTypes', varTypes, 'VariableNames', varNames);
            
            for n = 1:numel(cl)
                t(n,:) = {string(cl(n).cluster_name), string(cl(n).cluster_id), string(cl(n).state), string(cl(n).spark_version)};
            end

            if height(t) > 0
                app.UITable.Data = t;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ListClustersButton
        function listClusterPush(app, event)
            c = databricks.Cluster;
            app.UnknownLabel.Text = c.Host;
            cl = c.list;
            app.clusterList2Table(cl);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ListClustersButton = uibutton(app.UIFigure, 'push');
            app.ListClustersButton.ButtonPushedFcn = createCallbackFcn(app, @listClusterPush, true);
            app.ListClustersButton.Text = 'List Clusters';
            app.DatabricksEnvironmentLabel = uilabel(app.UIFigure);
            app.DatabricksEnvironmentLabel.Text = 'Databricks Environment:';
            app.UnknownLabel = uilabel(app.UIFigure);
            app.UnknownLabel.Text = 'Unknown';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Name'; 'Id'; 'State'; 'Spark Version'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ClusterTable

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end